classdef Relaxivity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RelaxationUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ButtonGroup                  matlab.ui.container.ButtonGroup
        LinearAnalysisButton         matlab.ui.control.RadioButton
        NonLinearAnalysisButton      matlab.ui.control.RadioButton
        NonLinearAnalysisLabel       matlab.ui.control.Label
        LinearAnalysisLabel          matlab.ui.control.Label
        T1InitialEstimate            matlab.ui.control.NumericEditField
        InitialIntensityEstimate     matlab.ui.control.NumericEditField
        InitialEstimatesLabel        matlab.ui.control.Label
        NoiseThresholdingEditField   matlab.ui.control.NumericEditField
        NoiseThresholdingEditFieldLabel  matlab.ui.control.Label
        SliceSpacingEditField        matlab.ui.control.NumericEditField
        SliceSpacingEditFieldLabel   matlab.ui.control.Label
        SliceSelectEditField         matlab.ui.control.NumericEditField
        SliceSelectEditFieldLabel    matlab.ui.control.Label
        AnalysisSelectDropDown       matlab.ui.control.DropDown
        AnalysisSelectDropDownLabel  matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        SelectaFolderLabel           matlab.ui.control.Label
        UploadButton                 matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        AnalysisButton               matlab.ui.control.Button
        ROIFunctionsDropDown         matlab.ui.control.DropDown
        ROIFunctionsDropDownLabel    matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        AnalysisMaxEditField         matlab.ui.control.NumericEditField
        MaxScaleLabel                matlab.ui.control.Label
        ImageSlider                  matlab.ui.control.Slider
        DebugLabel                   matlab.ui.control.Label
        AnalysisDisplay              matlab.ui.control.UIAxes
        ImageDisplay                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        DicomFolder % Inputted Folder
        DicomFiles % Paths to Each Individual File
        FileNum % Number of Total Files
        UsedFiles % Only the Files Being Looked At
        UsedFileNum % Number of Used Files
        Volume % All Data Put Together (Rows x Columns x # of Dicoms)
        
        InitialEstimate % Initial Estimate For Curve Fit
        InitialRelaxivityEstimate % Initial Relaxivity time Estimate
        AnalysisCurve % Curve Equation
        Threshold % Noise Level (data < threshold is ignored)
        AnalysisComplete = 0;
        CurrentROI;
        
        PointVals % Point y_data
        x_data % Timing Info
        DisplayedImage % Which DICOM to Show on the Figure to the Right
        DisplayImageIndex % Index of DICOMs to display
        Map % All T1/T2 values
    end  
    
    methods (Access = private)
        
        function updateFigures(app) 
            try
                % Reread only the files which the user cares about (skip first
                % two which are . and ..
                app.AnalysisComplete = 0;
                app.UsedFiles = app.DicomFiles(app.SliceSelectEditField.Value+2:app.SliceSpacingEditField.Value:app.FileNum);    
                app.UsedFileNum = numel(app.UsedFiles);
                app.ImageSlider.Limits = [1, app.UsedFileNum];
                app.DisplayedImage = dicomread(string(app.UsedFiles(app.ImageSlider.Value)));
                app.DebugLabel.Text = string(app.UsedFiles(app.ImageSlider.Value));
                imshow(app.DisplayedImage, DisplayRange=[], Parent=app.ImageDisplay)
            catch
                return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            inputpath = uigetdir();  %open a folder
            if inputpath == 0
                app.SelectaFolderLabel.Text = "Error Uploading";
                return;
            else
                app.DicomFolder = inputpath; % Store input as a property
                files = dir(inputpath);
                app.DicomFiles = fullfile({files.folder}, {files.name}); % Absolute path of each file
                app.FileNum = numel(app.DicomFiles(:));
                updateFigures(app);
                output = sprintf('Found %d files', app.FileNum);
                app.SelectaFolderLabel.Text = output;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            updateFigures(app);
            app.InitialEstimate = app.InitialIntensityEstimate.Value;
            app.InitialRelaxivityEstimate = app.T1InitialEstimate.Value;

            % Initialization
            [rows, cols] = size(app.DisplayedImage); % Find size of images
            app.Map = zeros(rows, cols, like=app.DisplayedImage); % initialize map
            app.Volume = zeros(rows, cols, app.UsedFileNum, like=app.DisplayedImage);
            app.x_data = zeros(app.UsedFileNum, 1);

            % Loop through each file to add its data to volume as well as
            % add the echo times to the x_data
            for n = 1:app.UsedFileNum
                app.Volume(:,:,n) = dicomread(string(app.UsedFiles(n)));
                app.x_data(n) = dicominfo(string(app.UsedFiles(n))).EchoTime;
            end
            
            % Initial guesses for solving the curve
            startPoints = [app.InitialEstimate, app.InitialRelaxivityEstimate];

            % Find all y points for each coordinate and perform the curve
            % fitting if it is above a certain threshold
            for i = 1:rows
                for j = 1:cols
                    if app.Volume(i, j, 1) > app.Threshold
                        if app.NonLinearAnalysisButton.Value
                           %% Using the Non-linear method (very long)
                           [fit_result, gof] = fit(app.x_data, squeeze(app.Volume(i, j, :)), app.AnalysisCurve, 'StartPoint', startPoints);
                           app.Map(i, j) = fit_result.t2;
                           app.DebugLabel.Text = "Point: " + string(i) + " " + string(j);
                        else
                           %% Using the Poly Fit Method
                           p = polyfit(app.x_data, log(double(squeeze(app.Volume(i, j, :)))), 1);
                           app.Map(i, j) = -1 / p(1);
                        end
                    end
                end
            end
            app.AnalysisComplete = 1;
            imagesc(app.AnalysisDisplay, app.Map)
            clim(app.AnalysisDisplay, [0 app.AnalysisMaxEditField.Value]);
            colorbar(app.AnalysisDisplay);
            % Estimate T1/T2
        end

        % Value changed function: SliceSpacingEditField
        function SpacingChanged(app, event)
            updateFigures(app);
        end

        % Value changed function: SliceSelectEditField
        function SliceSelectChanged(app, event)
            updateFigures(app);
        end

        % Value changed function: NoiseThresholdingEditField
        function ChangedNoiseThreshold(app, event)
            app.Threshold = app.NoiseThresholdingEditField.Value;
        end

        % Value changed function: ImageSlider
        function ImageSliderChanged(app, event)
            updateFigures(app);
        end

        % Value changing function: ImageSlider
        function ImageSliderChanging(app, event)
            updateFigures(app);
        end

        % Value changed function: AnalysisSelectDropDown
        function AnalysisChanged(app, event)
            value = app.AnalysisSelectDropDown.Value;
            if value == "T2/T2*"
                app.InitialIntensityEstimate.Value = 2000;
                app.T1InitialEstimate.Value = 50;
                app.AnalysisCurve = fittype('s0 * exp(-x / t2)', 'independent', 'x', 'dependent', 'y');
            elseif value == "T1"
                app.InitialIntensityEstimate.Value = 1000;
                app.T1InitialEstimate.Value = 10;
                app.AnalysisCurve = fittype('s0 * exp(-x / t2)', 'independent', 'x', 'dependent', 'y');
            elseif value == "T1*"
                app.InitialIntensityEstimate.Value = 1500;
                app.T1InitialEstimate.Value = 10;
                app.AnalysisCurve = fittype('s0 * exp(-x / t2)', 'independent', 'x', 'dependent', 'y');
            end
            
            
        end

        % Value changed function: AnalysisMaxEditField
        function ScaleChanged(app, event)
            if app.AnalysisComplete == 1
                clim(app.AnalysisDisplay, [0 app.AnalysisMaxEditField.Value]);
            end
        end

        % Callback function
        function ROIPointCalled(app, event)
            if app.AnalysisComplete == 1
                h = drawpoint();
                %NewUIFigure = uifigure("Position",[600 300 400 450]);
            else
                uialert(app.RelaxationUIFigure, "Complete Analysis Before Performing ROI Operations", "Error");
            end
        end

        % Callback function
        function ROIEllipseCalled(app, event)
            if app.AnalysisComplete == 1
                h = drawellipse(app.AnalysisDisplay);
                %NewUIFigure = uifigure("Position",[600 300 400 450]);
            else
                uialert(app.RelaxationUIFigure, "Complete Analysis Before Performing ROI Operations", "Error");
            end
        end

        % Value changed function: ROIFunctionsDropDown
        function ROISwitched(app, event)
            value = app.ROIFunctionsDropDown.Value;
            if app.AnalysisComplete == 1
                %Clear old ROI if it exists
                if ~isempty(app.CurrentROI) && isvalid(app.CurrentROI)
                    delete(app.CurrentROI);
                end
                switch value
                    case "Point"
                        app.CurrentROI = drawpoint(app.AnalysisDisplay);
                    case "Box"
                        app.CurrentROI = drawrectangle(app.AnalysisDisplay);
                    case "Ellipse"
                        app.CurrentROI = drawellipse(app.AnalysisDisplay);
                    case "Freedraw"
                        app.CurrentROI = drawfreehand(app.AnalysisDisplay);
                    otherwise
                        app.CurrentROI = [];
                end
            else
                alert = warndlg("Complete Analysis Before Performing ROI Operations", "Error");
                waitfor(alert);
                app.ROIFunctionsDropDown.Value = "None";
            end
        end

        % Button pushed function: AnalysisButton
        function RunAnalysisButton(app, event)
            %app.DebugLabel.Text = app.CurrentROI;
            binaryMask = createMask(app.CurrentROI);
            
            y_data = zeros(app.UsedFileNum, 1, like=app.Volume);

            % Loop through each file to add its data to volume as well as
            % add the echo times to the x_data
            for n = 1:app.UsedFileNum
                currentFrame = app.Volume(:,:,n);
                y_data(n) = mean(currentFrame(binaryMask));
            end

            NewUIFigure = uifigure("Position", [100 100 600 600]);
            
            NewAx = uiaxes(NewUIFigure, 'Position', [50 50 500 500]);
            plot(NewAx, app.x_data, y_data, '-o', 'LineWidth', 2);
            title(NewAx, 'T2 Decay Curve');
            xlabel(NewAx, 'Echo Time (ms)');
            ylabel(NewAx, 'Mean Intensity');
            grid(NewAx, 'on');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RelaxationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RelaxationUIFigure = uifigure('Visible', 'off');
            app.RelaxationUIFigure.Name = 'Relaxation';
            app.RelaxationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RelaxationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UploadButton = uibutton(app.LeftPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Tooltip = {'Upload a folder containing DICOMS'};
            app.UploadButton.Text = 'Upload';
            app.SelectaFolderLabel = uilabel(app.LeftPanel);
            app.SelectaFolderLabel.Text = 'Select a Folder';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnalysisSelectDropDownLabel = uilabel(app.LeftPanel);
            app.AnalysisSelectDropDownLabel.Text = 'Analysis Select';
            app.AnalysisSelectDropDown = uidropdown(app.LeftPanel);
            app.AnalysisSelectDropDown.Items = {'T1', 'T1*', 'T2/T2*'};
            app.AnalysisSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisChanged, true);
            app.AnalysisSelectDropDown.Value = 'T1';
            app.SliceSelectEditFieldLabel = uilabel(app.LeftPanel);
            app.SliceSelectEditFieldLabel.Text = 'Slice Select';
            app.SliceSelectEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SliceSelectEditField.Limits = [1 Inf];
            app.SliceSelectEditField.ValueChangedFcn = createCallbackFcn(app, @SliceSelectChanged, true);
            app.SliceSelectEditField.Value = 1;
            app.SliceSpacingEditFieldLabel = uilabel(app.LeftPanel);
            app.SliceSpacingEditFieldLabel.Text = 'Slice Spacing';
            app.SliceSpacingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SliceSpacingEditField.Limits = [1 Inf];
            app.SliceSpacingEditField.ValueChangedFcn = createCallbackFcn(app, @SpacingChanged, true);
            app.SliceSpacingEditField.Value = 1;
            app.NoiseThresholdingEditFieldLabel = uilabel(app.LeftPanel);
            app.NoiseThresholdingEditFieldLabel.Text = {'Noise'; 'Thresholding'};
            app.NoiseThresholdingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NoiseThresholdingEditField.ValueChangedFcn = createCallbackFcn(app, @ChangedNoiseThreshold, true);
            app.InitialEstimatesLabel = uilabel(app.LeftPanel);
            app.InitialEstimatesLabel.Text = 'Initial Estimates';
            app.InitialIntensityEstimate = uieditfield(app.LeftPanel, 'numeric');
            app.InitialIntensityEstimate.Limits = [0 Inf];
            app.T1InitialEstimate = uieditfield(app.LeftPanel, 'numeric');
            app.T1InitialEstimate.Limits = [0 Inf];
            app.LinearAnalysisLabel = uilabel(app.LeftPanel);
            app.LinearAnalysisLabel.Text = {'Linear'; 'Analysis'};
            app.NonLinearAnalysisLabel = uilabel(app.LeftPanel);
            app.NonLinearAnalysisLabel.Text = {'Non-Linear '; 'Analysis'};
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.NonLinearAnalysisButton = uiradiobutton(app.ButtonGroup);
            app.NonLinearAnalysisButton.Tooltip = {'Longer time (~ 5 min.) but higher accuracy'};
            app.NonLinearAnalysisButton.Text = '';
            app.LinearAnalysisButton = uiradiobutton(app.ButtonGroup);
            app.LinearAnalysisButton.Tooltip = {'Quicker but less accurate'};
            app.LinearAnalysisButton.Text = '';
            app.LinearAnalysisButton.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageDisplay = uiaxes(app.RightPanel);
            title(app.ImageDisplay, 'Image Display')
            app.ImageDisplay.XTick = [];
            app.ImageDisplay.YTick = [];
            app.AnalysisDisplay = uiaxes(app.RightPanel);
            title(app.AnalysisDisplay, 'Analysis')
            app.AnalysisDisplay.XTick = [];
            app.AnalysisDisplay.YTick = [];
            app.DebugLabel = uilabel(app.RightPanel);
            app.ImageSlider = uislider(app.RightPanel);
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderChanged, true);
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderChanging, true);
            app.ImageSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.ImageSlider.Step = 1;
            app.ImageSlider.Value = 1;
            app.MaxScaleLabel = uilabel(app.RightPanel);
            app.MaxScaleLabel.Text = 'Max. Scale';
            app.AnalysisMaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.AnalysisMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleChanged, true);
            app.AnalysisMaxEditField.Value = 100;
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.ROIFunctionsDropDownLabel = uilabel(app.RightPanel);
            app.ROIFunctionsDropDownLabel.Text = 'ROI Functions';
            app.ROIFunctionsDropDown = uidropdown(app.RightPanel);
            app.ROIFunctionsDropDown.Items = {'None', 'Point', 'Box', 'Ellipse', 'Freedraw'};
            app.ROIFunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ROISwitched, true);
            app.ROIFunctionsDropDown.Value = 'None';
            app.AnalysisButton = uibutton(app.RightPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton, true);
            app.AnalysisButton.Text = 'Analysis';
            % Show the figure after all components are created
            app.RelaxationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Relaxivity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RelaxationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RelaxationUIFigure)
        end
    end
end