classdef Relaxivity_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RelaxationUIFigure           matlab.ui.Figure
        RightPanel                   matlab.ui.container.Panel
        DefaultLabel                 matlab.ui.control.Label
        RelaxivityLabel              matlab.ui.control.Label
        MapType                      matlab.ui.container.ButtonGroup
        RelaxivityMapType            matlab.ui.control.RadioButton
        DefaultMapType               matlab.ui.control.RadioButton
        AnalyzeButton                matlab.ui.control.Button
        ROIFunctionsDropDown         matlab.ui.control.DropDown
        ROIFunctionsDropDownLabel    matlab.ui.control.Label
        AnalysisMaxEditField         matlab.ui.control.NumericEditField
        MaxScaleLabel                matlab.ui.control.Label
        ImageSlider                  matlab.ui.control.Slider
        AnalysisDisplay              matlab.ui.control.UIAxes
        ImageDisplay                 matlab.ui.control.UIAxes
        LeftPanel                    matlab.ui.container.Panel
        RunButton                    matlab.ui.control.Button
        RunDebugLabel                matlab.ui.control.Label
        UploadDebugLabel             matlab.ui.control.Label
        NonLinearAnalysisLabel       matlab.ui.control.Label
        LinearAnalysisLabel          matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        NonLinearAnalysisButton      matlab.ui.control.RadioButton
        LinearAnalysisButton         matlab.ui.control.RadioButton
        T1InitialEstimate            matlab.ui.control.NumericEditField
        InitialIntensityEstimate     matlab.ui.control.NumericEditField
        InitialEstimatesLabel        matlab.ui.control.Label
        NoiseThresholdingEditField   matlab.ui.control.NumericEditField
        NoiseThresholdingEditFieldLabel  matlab.ui.control.Label
        SliceSpacingEditField        matlab.ui.control.NumericEditField
        SliceSpacingEditFieldLabel   matlab.ui.control.Label
        SliceSelectEditField         matlab.ui.control.NumericEditField
        SliceSelectEditFieldLabel    matlab.ui.control.Label
        AnalysisSelectDropDown       matlab.ui.control.DropDown
        AnalysisSelectDropDownLabel  matlab.ui.control.Label
        SelectaFolderLabel           matlab.ui.control.Label
        UploadButton                 matlab.ui.control.Button
    end

    properties (Access = private)
        DicomFolder % Inputted Folder
        DicomFiles % Paths to Each Individual File
        FileNum % Number of Total Files
        UsedFiles % Only the Files Being Looked At
        UsedFileNum % Number of Used Files
        Volume % All Data Put Together (Rows x Columns x # of Dicoms)
        
        InitialEstimate % Initial Estimate For Curve Fit
        InitialRelaxivityEstimate % Initial Relaxivity Time Estimate
        AnalysisCurve % Curve Equation
        Threshold % Noise Level (data < threshold is ignored)

        AnalysisComplete = 0; % 0 or 1
        CurrentROI; % Contains Information about ROI Positioning Once Drawn
        
        PointVals % Point y_data
        x_data % Timing Info
        DisplayedImage % Which DICOM to Show on the Figure to the Right
        DisplayImageIndex % Index of DICOMs to display
        Map % All T1/T2 values
    end  
    
    methods (Access = private)
        
        function updateFigures(app) 
            try
                % Reread only the files which the user cares about (skip first
                % two which are always . and ..)
                app.AnalysisComplete = 0;
                app.UsedFiles = app.DicomFiles(app.SliceSelectEditField.Value+2:app.SliceSpacingEditField.Value:app.FileNum);    
                app.UsedFileNum = numel(app.UsedFiles);
                app.ImageSlider.Limits = [1, app.UsedFileNum];
                app.DisplayedImage = dicomread(string(app.UsedFiles(app.ImageSlider.Value)));
                app.UploadDebugLabel.Text = "Displaying: " + ...
                    string(app.UsedFiles(app.ImageSlider.Value));
                imshow(app.DisplayedImage, DisplayRange=[], Parent=app.ImageDisplay)
            catch
                return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            inputpath = uigetdir();  %open a folder
            if inputpath == 0
                app.SelectaFolderLabel.Text = "Error Uploading";
                return;
            else
                app.DicomFolder = inputpath; % Store input as a property
                files = dir(inputpath);
                app.DicomFiles = fullfile({files.folder}, {files.name}); % Absolute path of each file
                app.FileNum = numel(app.DicomFiles(:));
                updateFigures(app);
                output = sprintf('Found %d files', app.FileNum);
                app.SelectaFolderLabel.Text = output;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            updateFigures(app);
            app.ROIFunctionsDropDown.Value = "None";
            app.InitialEstimate = app.InitialIntensityEstimate.Value;
            app.InitialRelaxivityEstimate = app.T1InitialEstimate.Value;

            % Initialization
            [rows, cols] = size(app.DisplayedImage); % Find size of images
            app.Map = zeros(rows, cols, like=app.DisplayedImage); % initialize map
            app.Volume = zeros(rows, cols, app.UsedFileNum, like=app.DisplayedImage);
            app.x_data = zeros(app.UsedFileNum, 1);

            % Loop through each file to add its data to volume as well as
            % add the echo times to the x_data
            for n = 1:app.UsedFileNum
                try
                    app.Volume(:,:,n) = dicomread(string(app.UsedFiles(n)));
                    % Switch statement to select correct timing parameters
                    switch app.AnalysisSelectDropDown.Value
                    case "T1"
                        app.x_data(n) = dicominfo(string(app.UsedFiles(n))).RepititionTime;
                    case "T1*"
                        app.x_data(n) = dicominfo(string(app.UsedFiles(n))).InversionTime;
                    case "T2/T2*"
                        app.x_data(n) = dicominfo(string(app.UsedFiles(n))).EchoTime;
                    end
                catch % If no timing is found, inform user
                    alert = warndlg("DICOM metadata for " + app.AnalysisSelectDropDown.Value + ...
                        " image not found. Double check folder and location", "Error");
                    waitfor(alert);
                    return;
                end
            end
            
            % Initial guesses for solving the curve
            startPoints = [app.InitialEstimate, app.InitialRelaxivityEstimate];

            % Find all y points for each coordinate and perform the curve
            % fitting if it is above a certain threshold
            for i = 1:rows
                for j = 1:cols
                    if app.Volume(i, j, 1) > app.Threshold
                        if app.NonLinearAnalysisButton.Value
                           % Using the Non-linear method (very long)
                           try
                               [fit_result, ~] = fit(app.x_data, squeeze(app.Volume(i, j, :)), app.AnalysisCurve, 'StartPoint', startPoints);
                               app.Map(i, j) = fit_result.r;
                           catch
                               % Use polyfit in case of error
                               p = polyfit(app.x_data, log(double(squeeze(app.Volume(i, j, :)))), 1);
                               app.Map(i, j) = -1 / p(1);
                           end
                           app.RunDebugLabel.Text = "Point: [" + string(i) + " " + string(j) + "] out of " + ...
                               "[" + string(rows) + " " + string(cols) + "]";
                        else
                           % Using the Poly Fit Method
                           p = polyfit(app.x_data, log(double(squeeze(app.Volume(i, j, :)))), 1);
                           app.Map(i, j) = -1 / p(1);
                        end
                    end
                end
            end
            app.AnalysisComplete = 1;
            clim(app.AnalysisDisplay, [0 app.AnalysisMaxEditField.Value]);
            if app.DefaultMapType.Value
                imagesc(app.AnalysisDisplay, app.Map)
                colormap(app.AnalysisDisplay, jet)
            else
                imagesc(app.AnalysisDisplay, app.Map.^-1)
                colormap(app.AnalysisDisplay, flipud(jet))
            end
            colorbar(app.AnalysisDisplay);
        end

        % Value changed function: SliceSpacingEditField
        function SpacingChanged(app, event)
            updateFigures(app);
        end

        % Value changed function: SliceSelectEditField
        function SliceSelectChanged(app, event)
            updateFigures(app);
        end

        % Value changed function: NoiseThresholdingEditField
        function ChangedNoiseThreshold(app, event)
            app.Threshold = app.NoiseThresholdingEditField.Value;
        end

        % Value changed function: ImageSlider
        function ImageSliderChanged(app, event)
            updateFigures(app);
        end

        % Value changing function: ImageSlider
        function ImageSliderChanging(app, event)
            updateFigures(app);
        end

        % Value changed function: AnalysisSelectDropDown
        function AnalysisChanged(app, event)
            % Choose initial values and curve to fit to based on analysis
            % selection
            value = app.AnalysisSelectDropDown.Value;
            if value == "T2/T2*"
                app.InitialIntensityEstimate.Value = 2000;
                app.T1InitialEstimate.Value = 50;
                app.AnalysisCurve = fittype('s0 * exp(-x / r)', 'independent', 'x', 'dependent', 'y');
            elseif value == "T1"
                app.InitialIntensityEstimate.Value = 1000;
                app.T1InitialEstimate.Value = 10;
                app.AnalysisCurve = fittype('s0 * (1 - exp(-x / r)', 'independent', 'x', 'dependent', 'y');
            elseif value == "T1*"
                app.InitialIntensityEstimate.Value = 1500;
                app.T1InitialEstimate.Value = 10;
                app.AnalysisCurve = fittype('-s0 * exp(-x / r)', 'independent', 'x', 'dependent', 'y');
            end
            
            
        end

        % Value changed function: AnalysisMaxEditField
        function ScaleChanged(app, event)
            if app.AnalysisComplete == 1
                clim(app.AnalysisDisplay, [0 app.AnalysisMaxEditField.Value]);
            end
        end

        % Value changed function: ROIFunctionsDropDown
        function ROISwitched(app, event)
            value = app.ROIFunctionsDropDown.Value;
            if app.AnalysisComplete == 1
                %Clear old ROI if it exists
                if ~isempty(app.CurrentROI) && isvalid(app.CurrentROI)
                    delete(app.CurrentROI);
                end
                switch value
                    case "Point"
                        app.CurrentROI = drawpoint(app.AnalysisDisplay);
                    case "Box"
                        app.CurrentROI = drawrectangle(app.AnalysisDisplay);
                    case "Ellipse"
                        app.CurrentROI = drawellipse(app.AnalysisDisplay);
                    case "Freedraw"
                        app.CurrentROI = drawfreehand(app.AnalysisDisplay);
                    otherwise
                        app.CurrentROI = [];
                end
            else
                alert = warndlg("Complete Analysis Before Performing ROI Operations", "Error");
                waitfor(alert);
                app.ROIFunctionsDropDown.Value = "None";
            end
        end

        % Button pushed function: AnalyzeButton
        function RunAnalyzeButton(app, event)
            if app.ROIFunctionsDropDown.Value ~= "Point"
                binaryMask = createMask(app.CurrentROI);
            else
                [rows, cols] = size(app.DisplayedImage);
                binaryMask = zeros(rows, cols, like=app.DisplayedImage);
                binaryMask(round(app.CurrentROI.Position(1)), round(app.CurrentROI.Position(2))) = 1;
                binaryMask = logical(binaryMask);
            end
            y_data = zeros(app.UsedFileNum, 1, like=app.Volume);

            % Loop through each file to add its data to volume as well as
            % add the echo times to the x_data
            for n = 1:app.UsedFileNum
                currentFrame = app.Volume(:,:,n);
                y_data(n) = mean(currentFrame(binaryMask));
            end

            NewUIFigure = uifigure("Position", [100 100 600 600]);
            
            startPoints = [app.InitialEstimate, app.InitialRelaxivityEstimate];
            [fit_result, ~] = fit(app.x_data, y_data(:), app.AnalysisCurve, 'StartPoint', startPoints);
            %app.Map(i, j) = fit_result.t2;

            NewAx = uiaxes(NewUIFigure, 'Position', [50 50 500 500]);
            plot(NewAx, app.x_data, y_data, '-', "DisplayName" , "Relaxation Curve", 'LineWidth', 2);
            title(NewAx, 'T2 Decay Curve');
            xlabel(NewAx, 'Echo Time (ms)');
            ylabel(NewAx, 'Mean Intensity');
            xline(NewAx, fit_result.r, '--',"DisplayName" , app.AnalysisSelectDropDown.Value + " = " + fit_result.r + " ms", 'Color', [0.9, 0.7, 0.7], 'LineWidth', 2);
            legend(NewAx);
            grid(NewAx, 'on');
        end

        % Selection changed function: MapType
        function MapTypeChanged(app, event)
            if app.DefaultMapType.Value
                imagesc(app.AnalysisDisplay, app.Map)
                colormap(app.AnalysisDisplay, jet)
                app.AnalysisMaxEditField.Value = 100;
            else
                imagesc(app.AnalysisDisplay, double(app.Map).^-1)
                colormap(app.AnalysisDisplay, flipud(jet))
                app.AnalysisMaxEditField.Value = 0.1;
            end
            clim(app.AnalysisDisplay, [0 app.AnalysisMaxEditField.Value]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RelaxationUIFigure = uifigure('Visible', 'off');
            app.RelaxationUIFigure.Name = 'Relaxation';
            app.LeftPanel = uipanel(app.RelaxationUIFigure);
            app.UploadButton = uibutton(app.LeftPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Tooltip = {'Upload a folder containing DICOMS'};
            app.UploadButton.Text = 'Upload';
            app.SelectaFolderLabel = uilabel(app.LeftPanel);
            app.SelectaFolderLabel.Text = 'Select a Folder';
            app.AnalysisSelectDropDownLabel = uilabel(app.LeftPanel);
            app.AnalysisSelectDropDownLabel.Text = 'Analysis Select';
            app.AnalysisSelectDropDown = uidropdown(app.LeftPanel);
            app.AnalysisSelectDropDown.Items = {'T1', 'T1*', 'T2/T2*'};
            app.AnalysisSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisChanged, true);
            app.AnalysisSelectDropDown.Value = 'T1';
            app.SliceSelectEditFieldLabel = uilabel(app.LeftPanel);
            app.SliceSelectEditFieldLabel.Text = 'Slice Select';
            app.SliceSelectEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SliceSelectEditField.Limits = [1 Inf];
            app.SliceSelectEditField.ValueChangedFcn = createCallbackFcn(app, @SliceSelectChanged, true);
            app.SliceSelectEditField.Value = 1;
            app.SliceSpacingEditFieldLabel = uilabel(app.LeftPanel);
            app.SliceSpacingEditFieldLabel.Text = 'Slice Spacing';
            app.SliceSpacingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SliceSpacingEditField.Limits = [1 Inf];
            app.SliceSpacingEditField.ValueChangedFcn = createCallbackFcn(app, @SpacingChanged, true);
            app.SliceSpacingEditField.Value = 1;
            app.NoiseThresholdingEditFieldLabel = uilabel(app.LeftPanel);
            app.NoiseThresholdingEditFieldLabel.Text = {'Noise'; 'Thresholding'};
            app.NoiseThresholdingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NoiseThresholdingEditField.ValueChangedFcn = createCallbackFcn(app, @ChangedNoiseThreshold, true);
            app.InitialEstimatesLabel = uilabel(app.LeftPanel);
            app.InitialEstimatesLabel.Text = 'Initial Estimates';
            app.InitialIntensityEstimate = uieditfield(app.LeftPanel, 'numeric');
            app.InitialIntensityEstimate.Limits = [0 Inf];
            app.InitialIntensityEstimate.Value = 1000;
            app.T1InitialEstimate = uieditfield(app.LeftPanel, 'numeric');
            app.T1InitialEstimate.Limits = [0 Inf];
            app.T1InitialEstimate.Value = 10;
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.LinearAnalysisButton = uiradiobutton(app.ButtonGroup);
            app.LinearAnalysisButton.Tooltip = {'Quicker but less accurate'};
            app.LinearAnalysisButton.Text = '';
            app.LinearAnalysisButton.Value = true;
            app.NonLinearAnalysisButton = uiradiobutton(app.ButtonGroup);
            app.NonLinearAnalysisButton.Tooltip = {'Longer time (~ 5 min.) but higher accuracy'};
            app.NonLinearAnalysisButton.Text = '';
            app.LinearAnalysisLabel = uilabel(app.LeftPanel);
            app.LinearAnalysisLabel.Text = {'Linear'; 'Analysis'};
            app.NonLinearAnalysisLabel = uilabel(app.LeftPanel);
            app.NonLinearAnalysisLabel.Text = {'Non-Linear '; 'Analysis'};
            app.UploadDebugLabel = uilabel(app.LeftPanel);
            app.UploadDebugLabel.Text = '';
            app.RunDebugLabel = uilabel(app.LeftPanel);
            app.RunDebugLabel.Text = '';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.RelaxationUIFigure);
            app.ImageDisplay = uiaxes(app.RightPanel);
            title(app.ImageDisplay, 'Image Display')
            app.ImageDisplay.XTick = [];
            app.ImageDisplay.YTick = [];
            app.AnalysisDisplay = uiaxes(app.RightPanel);
            title(app.AnalysisDisplay, 'Analysis')
            app.AnalysisDisplay.XTick = [];
            app.AnalysisDisplay.YTick = [];
            app.ImageSlider = uislider(app.RightPanel);
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderChanged, true);
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderChanging, true);
            app.ImageSlider.Step = 1;
            app.ImageSlider.Value = 1;
            app.MaxScaleLabel = uilabel(app.RightPanel);
            app.MaxScaleLabel.Text = 'Max. Scale';
            app.AnalysisMaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.AnalysisMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleChanged, true);
            app.AnalysisMaxEditField.Value = 100;
            app.ROIFunctionsDropDownLabel = uilabel(app.RightPanel);
            app.ROIFunctionsDropDownLabel.Text = 'ROI Functions';
            app.ROIFunctionsDropDown = uidropdown(app.RightPanel);
            app.ROIFunctionsDropDown.Items = {'None', 'Point', 'Box', 'Ellipse', 'Freedraw'};
            app.ROIFunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ROISwitched, true);
            app.ROIFunctionsDropDown.Value = 'None';
            app.AnalyzeButton = uibutton(app.RightPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalyzeButton, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.MapType = uibuttongroup(app.RightPanel);
            app.MapType.SelectionChangedFcn = createCallbackFcn(app, @MapTypeChanged, true);
            app.DefaultMapType = uiradiobutton(app.MapType);
            app.DefaultMapType.Text = '';
            app.DefaultMapType.Value = true;
            app.RelaxivityMapType = uiradiobutton(app.MapType);
            app.RelaxivityMapType.Text = '';
            app.RelaxivityLabel = uilabel(app.RightPanel);
            app.RelaxivityLabel.Text = 'Relaxivity';
            app.DefaultLabel = uilabel(app.RightPanel);
            app.DefaultLabel.Text = 'Default';
            % Show the figure after all components are created
            app.RelaxationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Relaxivity_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RelaxationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RelaxationUIFigure)
        end
    end
end